classdef Motion_Analysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MainGridLayout               matlab.ui.container.GridLayout
        PlayPauseButton              matlab.ui.control.Button
        LocalFrameDropDown           matlab.ui.control.DropDown
        LocalFrameDropDownLabel      matlab.ui.control.Label
        DataPanel                    matlab.ui.container.Panel
        DataGridLayout               matlab.ui.container.GridLayout
        OnwhichaxisLabel             matlab.ui.control.Label
        LocalFrameChoice2Label       matlab.ui.control.Label
        LocalFrameChoice1Label       matlab.ui.control.Label
        LocalFrameDropDown_3         matlab.ui.control.DropDown
        LocalFrameDropDown_2         matlab.ui.control.DropDown
        LocalFrameDropDown_2Label    matlab.ui.control.Label
        AxesDropDown                 matlab.ui.control.DropDown
        CRPAxes2                     matlab.ui.control.UIAxes
        CRPAxes1                     matlab.ui.control.UIAxes
        SpectrumAnalysisAxes         matlab.ui.control.UIAxes
        TimeSlider                   matlab.ui.control.Slider
        ExportDataGeneratePDFButton  matlab.ui.control.Button
        ManAxes                      matlab.ui.control.UIAxes
        LocalFrameAxes               matlab.ui.control.UIAxes
        Flexion                      matlab.ui.control.UIAxes
        Abduction                    matlab.ui.control.UIAxes
        AxialRotation                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tsv
        t
        local_frame_string
        compute_type
        current_axis
        isAnimating = false
        animationTimer
    end
    
    methods (Access = private)
        % Plot all our created plots in the GUI
        function updatePlots(app)
            % Plot the whole man
            gui_3D_fullbody(app.tsv, app.t, app.ManAxes);
            % Plot the local_Frame
            gui_localframe_3d_lifetime(app.tsv, app.local_frame_string, app.LocalFrameAxes);
            % Plot the angles
            plot_angles(app.tsv, app.local_frame_string, app.Flexion, app.Abduction, app.AxialRotation);
            % Plot spectrum analysis
            mean_x = sum(app.tsv{:,:}, 2);
            fs = 300; % Sampling frequency
            gui_spectrum_analysis(app.SpectrumAnalysisAxes, mean_x, fs);
            % Plot the Continuous Relative Phase
            app.plot_continuous_relative_phase();
       end

        % Helper function to plot the right CRP (Continious Relative Phase)
        function plot_continuous_relative_phase(app)
             fs = 300; % Sampling Frequency
             fcu = 10; % Cutoff Frequency
             order = 4; % Filter Order
             localFrame1 = app.LocalFrameDropDown_2.Value;
             localFrame2 = app.LocalFrameDropDown_3.Value;
             [flexion1, abduction1, axial_rotation1] = return_filtered_angles(app.tsv, localFrame1);
             [flexion2, abduction2, axial_rotation2] = return_filtered_angles(app.tsv, localFrame2);
             switch app.current_axis
                 case 'X'
                     signal1 = flexion1;
                     signal2 = flexion2;
                     name_signal_1 = ['Flexion: ', localFrame1];
                     name_signal_2 = ['Flexion: ', localFrame2];
                 case 'Y'
                     signal1 = abduction1;
                     signal2 = abduction2;
                     name_signal_1 = ['Abduction: ', localFrame1];
                     name_signal_2 = ['Abduction: ', localFrame2];
                 case 'Z'
                     signal1 = axial_rotation1;
                     signal2 = axial_rotation2;
                     name_signal_1 = ['Axial Rotation: ', localFrame1];
                     name_signal_2 = ['Axial Rotation: ', localFrame2];
             end
             gui_continuous_relative_phase(app.compute_type, signal1, signal2, name_signal_1, name_signal_2, fs, fcu, order, app.CRPAxes1, app.CRPAxes2);
        end

        % Pop up to fill in personal details (generate PDF)
        function userDetails = promptUserDetails(app)
            % Define the prompts
            prompts = {'Age:', 'Gender:', 'Height (cm):', 'Body Weight (kg):', 'Years of Baseball:', 'Club:'};
            dlgTitle = 'Enter Personal Details';
            numLines = 1;
    
            % Show input dialog
            defaultAnswers = {'', '', '', '', '', ''};
            userInput = inputdlg(prompts, dlgTitle, numLines, defaultAnswers);
    
            % If user cancels, return empty
            if isempty(userInput)
                userDetails = [];
                return;
            end
    
            % Save user input
            userDetails.Age = userInput{1};
            userDetails.Gender = userInput{2};
            userDetails.Height = userInput{3};
            userDetails.Weight = userInput{4};
            userDetails.BaseballYears = userInput{5};
            userDetails.Club = userInput{6};
        end

        % method to advance animation frame
        function animateFrame(app)
            % Normally do +1 with higher frequency but for now +5
            app.t = app.t + 5;
            if app.t > height(app.tsv)
                app.isAnimating = false;
                stop(app.animationTimer);
                app.PlayPauseButton.Text = 'Play Animation';
                app.t = 1;
            end
            app.TimeSlider.Value = app.t;
            % Only update the 3D man plot because otherwise too much
            % computing per frame
            gui_3D_fullbody(app.tsv, app.t, app.ManAxes);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainGridLayout.Scrollable = 'on';
            % Read the tsv file and store in app.tsv
            app.tsv = readtable("10Ax1.tsv","FileType","text","Delimiter","\t"); 
            % Set the slider for t, based on the tsv file length, and only
            % integers
            app.TimeSlider.Limits = [1 height(app.tsv)];
            app.TimeSlider.MajorTicks = round(linspace(1, height(app.tsv), 6));
            app.TimeSlider.MajorTickLabels = string(app.TimeSlider.MajorTicks);
            app.TimeSlider.Value = 1;
            % Set out private properties to initial value
            app.t = app.TimeSlider.Value;
            app.local_frame_string = app.LocalFrameDropDown.Value;
            app.isAnimating = false;
            app.compute_type = "norm";
            app.current_axis = "X";
            % Creating timing method for the dynamic man plot
            % Every 0.5 seconds plot new value
            app.animationTimer = timer('Period', 0.5, 'executionMode', 'fixedRate', 'TimerFcn', @(~,~) app.animateFrame);
            % start all the plot methods
            app.updatePlots();
        end

        % Callback function
        function Part(app, event)

        end

        % Callback function
        function showAngles(app, event)

        end

        % Callback function
        function plot_man(app, event)

        end

        % Callback function
        function showFlexionAngles(app, event)

        end

        % Callback function
        function show_angles(app, event)
            
        end

        % Callback function
        function plot_man_part(app, event)

        end

        % Value changed function: TimeSlider
        function TimeTimeSliderValueChanged(app, event)
            % Make an integer of the slider value, so we can actually use
            % it
            newTimeValue = round(app.TimeSlider.Value);
            % Only update plots if the change came from user interaction
            % with the slider, or if we aren't animating
            if (~app.isAnimating) || (app.t ~= newTimeValue)
                app.t = newTimeValue;
                app.updatePlots();
            end
        end

        % Callback function
        function UIClose(app, event)

        end

        % Value changed function: LocalFrameDropDown
        function local_frame_change(app, event)
            app.local_frame_string = app.LocalFrameDropDown.Value;
            app.updatePlots();
        end

        % Callback function
        function Time(app, event)

        end

        % Button pushed function: ExportDataGeneratePDFButton
        function generatePDF(app, event)
            % Prompt user for personal details
            userDetails = app.promptUserDetails();
            % Prompt user to select a location and filename
            [file, path] = uiputfile('*.pdf', 'Save PDF As');
            if isequal(file, 0)
                % User cancelled
                uialert(app.UIFigure, 'PDF generation cancelled by user.', 'Cancelled');
                return;
            end
            pdfFilePath = fullfile(path, file);
            
            % Create the first page (Personal details placeholder)
            fig = figure('Visible', 'off', 'Position', [100 100 1200 800]);
            fig.Color = 'w';
            grid(gca, 'off');
 
            % add Personal Details
            text(0.1, 0.8, 'Personal Details', 'FontSize', 20, 'FontWeight', 'bold');
            text(0.1, 0.6, ['Age: ', userDetails.Age], 'FontSize', 16);
            text(0.1, 0.5, ['Gender: ', userDetails.Gender], 'FontSize', 16);
            text(0.1, 0.4, ['Height (cm): ', userDetails.Height], 'FontSize', 16);
            text(0.1, 0.3, ['Body Weight (kg): ', userDetails.Weight], 'FontSize', 16);
            text(0.1, 0.2, ['Years of Baseball: ', userDetails.BaseballYears], 'FontSize', 16);
            text(0.1, 0.1, ['Club: ', userDetails.Club], 'FontSize', 16);
            
            % Save the first page
            exportgraphics(gcf, pdfFilePath, 'Append', false);
            close(fig);
            
            % Loop through all local frames and create a page per frame
            for i = 1:length(app.LocalFrameDropDown.Items)
                local_frame = app.LocalFrameDropDown.Items{i};
                
                % Create a new figure for each local frame
                fig = figure('Visible', 'off', 'Position', [100 100 1200 800]);
                fig.Color = 'w';

                clf(fig);
                % Create subplots for Euler/Cardan angles
                ax1 = subplot(3,3,1);
                ax2 = subplot(3,3,2);
                ax3 = subplot(3,3,3);
                
                % Use the existing plot_angles function just like in your GUI
                plot_angles(app.tsv, local_frame, ax1, ax2, ax3);
                
                % Add titles to each subplot
                title(ax1, sprintf('%s - Flexion', local_frame), 'Interpreter', 'none');
                title(ax2, sprintf('%s - Abduction', local_frame), 'Interpreter', 'none');
                title(ax3, sprintf('%s - Axial Rotation', local_frame), 'Interpreter', 'none');

                % Create subplots for Velocities
                ax4 = subplot(3,3,4);
                ax5 = subplot(3,3,5);
                ax6 = subplot(3,3,6);
                % Create subplots for Accelerations
                ax7 = subplot(3,3,7);
                ax8 = subplot(3,3,8);
                ax9 = subplot(3,3,9);

                plot_vel_acc(app.tsv, local_frame, ax4, ax5, ax6, ax7, ax8, ax9);
   
                % Save the current figure as the next page in the PDF
                exportgraphics(gcf, pdfFilePath, 'Append', true);
                close(fig);
            end
            
            % Notify the user
            uiconfirm(app.UIFigure, 'Multi-page PDF has been successfully saved!', 'Success', 'Icon', 'success');
        end

        % Callback function: not associated with a component
        function inputDataSpectrum(app, event)
            
        end

        % Value changed function: LocalFrameDropDown_2, 
        % ...and 1 other component
        function plot_CRP(app, event)
            % Plot the Continuous Relative Phase
            app.plot_continuous_relative_phase();
        end

        % Callback function: not associated with a component
        function compute_type_change(app, event)
            app.compute_type = app.ComputeTypeDropDown.Value;
            app.updatePlots();
        end

        % Button pushed function: PlayPauseButton
        function play_pause_button_pushed(app, event)
            app.isAnimating = ~app.isAnimating;
            if app.isAnimating
                start(app.animationTimer);
                app.PlayPauseButton.Text = 'Pause Animation';
            else
                stop(app.animationTimer);
                app.PlayPauseButton.Text = 'Play Animation';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.AxialRotation = uiaxes(app.MainGridLayout);
            title(app.AxialRotation, 'Axial Rotation Angles')
            xlabel(app.AxialRotation, 'X')
            ylabel(app.AxialRotation, 'Y')
            zlabel(app.AxialRotation, 'Z')
            app.Abduction = uiaxes(app.MainGridLayout);
            title(app.Abduction, 'Abduction Angles')
            xlabel(app.Abduction, 'X')
            ylabel(app.Abduction, 'Y')
            zlabel(app.Abduction, 'Z')
            app.Flexion = uiaxes(app.MainGridLayout);
            title(app.Flexion, 'Flexion Angles')
            xlabel(app.Flexion, 'X')
            ylabel(app.Flexion, 'Y')
            zlabel(app.Flexion, 'Z')
            app.LocalFrameAxes = uiaxes(app.MainGridLayout);
            title(app.LocalFrameAxes, '3D Body Part Plot')
            xlabel(app.LocalFrameAxes, 'X')
            ylabel(app.LocalFrameAxes, 'Y')
            zlabel(app.LocalFrameAxes, 'Z')
            app.ManAxes = uiaxes(app.MainGridLayout);
            title(app.ManAxes, '3D Man Plot')
            xlabel(app.ManAxes, 't')
            app.ManAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ExportDataGeneratePDFButton = uibutton(app.MainGridLayout, 'push');
            app.ExportDataGeneratePDFButton.ButtonPushedFcn = createCallbackFcn(app, @generatePDF, true);
            app.ExportDataGeneratePDFButton.Text = 'Export Data (Generate PDF)';
            app.TimeSlider = uislider(app.MainGridLayout);
            app.TimeSlider.Limits = [0 600];
            app.TimeSlider.MajorTicks = [100 200 300 400 500 600];
            app.TimeSlider.MajorTickLabels = {'100', '200', '300', '400', '500', '600'};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeTimeSliderValueChanged, true);
            app.DataPanel = uipanel(app.MainGridLayout);
            app.DataPanel.Title = 'Data';
            app.DataGridLayout = uigridlayout(app.DataPanel);
            app.SpectrumAnalysisAxes = uiaxes(app.DataGridLayout);
            title(app.SpectrumAnalysisAxes, 'Title')
            xlabel(app.SpectrumAnalysisAxes, 'X')
            ylabel(app.SpectrumAnalysisAxes, 'Y')
            zlabel(app.SpectrumAnalysisAxes, 'Z')
            app.CRPAxes1 = uiaxes(app.DataGridLayout);
            title(app.CRPAxes1, 'Title')
            xlabel(app.CRPAxes1, 'X')
            ylabel(app.CRPAxes1, 'Y')
            zlabel(app.CRPAxes1, 'Z')
            app.CRPAxes2 = uiaxes(app.DataGridLayout);
            title(app.CRPAxes2, 'Title')
            xlabel(app.CRPAxes2, 'X')
            ylabel(app.CRPAxes2, 'Y')
            zlabel(app.CRPAxes2, 'Z')
            app.AxesDropDown = uidropdown(app.DataGridLayout);
            app.AxesDropDown.Items = {'X', 'Y', 'Z'};
            app.AxesDropDown.Value = 'X';
            app.LocalFrameDropDown_2Label = uilabel(app.DataGridLayout);
            app.LocalFrameDropDown_2Label.Text = 'Local Frame:';
            app.LocalFrameDropDown_2 = uidropdown(app.DataGridLayout);
            app.LocalFrameDropDown_2.Items = {'upper arm_m1', 'upper arm_m2', 'lower arm', 'core motion', 'thorax', 'pelvis', 'left knee'};
            app.LocalFrameDropDown_2.ValueChangedFcn = createCallbackFcn(app, @plot_CRP, true);
            app.LocalFrameDropDown_2.Value = 'upper arm_m1';
            app.LocalFrameDropDown_3 = uidropdown(app.DataGridLayout);
            app.LocalFrameDropDown_3.Items = {'upper arm_m1', 'upper arm_m2', 'lower arm', 'core motion', 'thorax', 'pelvis', 'left knee'};
            app.LocalFrameDropDown_3.ValueChangedFcn = createCallbackFcn(app, @plot_CRP, true);
            app.LocalFrameDropDown_3.Value = 'upper arm_m1';
            app.LocalFrameChoice1Label = uilabel(app.DataGridLayout);
            app.LocalFrameChoice1Label.Text = 'Local Frame Choice 1:';
            app.LocalFrameChoice2Label = uilabel(app.DataGridLayout);
            app.LocalFrameChoice2Label.Text = 'Local Frame Choice 2:';
            app.OnwhichaxisLabel = uilabel(app.DataGridLayout);
            app.OnwhichaxisLabel.Text = 'On which axis?:';
            app.LocalFrameDropDownLabel = uilabel(app.MainGridLayout);
            app.LocalFrameDropDownLabel.Text = 'Local Frame:';
            app.LocalFrameDropDown = uidropdown(app.MainGridLayout);
            app.LocalFrameDropDown.Items = {'upper arm_m1', 'upper arm_m2', 'lower arm', 'core motion', 'thorax', 'pelvis', 'left knee'};
            app.LocalFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @local_frame_change, true);
            app.LocalFrameDropDown.Value = 'upper arm_m1';
            app.PlayPauseButton = uibutton(app.MainGridLayout, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @play_pause_button_pushed, true);
            app.PlayPauseButton.Text = 'Play Animation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motion_Analysis_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end